classdef IPL_NguyenXuanHao_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        ToolsDropDownLabel      matlab.ui.control.Label
        ToolsDropDown           matlab.ui.control.DropDown
        ImportImageButton       matlab.ui.control.Button
        BlurBackgroundButton    matlab.ui.control.Button
        RemoveBackgroundButton  matlab.ui.control.Button
        IMAGEPROCESSINGPROJECTNGUYENXUANHAOEEACIU19005Label  matlab.ui.control.Label
        ExportImageButton       matlab.ui.control.Button
        TabGroup                matlab.ui.container.TabGroup
        BrightnessTab           matlab.ui.container.Tab
        BrightnessSliderLabel   matlab.ui.control.Label
        BrightnessSlider        matlab.ui.control.Slider
        SaveButton              matlab.ui.control.Button
        ColorchannelsTab        matlab.ui.container.Tab
        RChannelSliderLabel     matlab.ui.control.Label
        RChannelSlider          matlab.ui.control.Slider
        GChannelSliderLabel     matlab.ui.control.Label
        GChannelSlider          matlab.ui.control.Slider
        BChannelSliderLabel     matlab.ui.control.Label
        BChannelSlider          matlab.ui.control.Slider
        SaveButton_2            matlab.ui.control.Button
        SaveButton_3            matlab.ui.control.Button
        SaveButton_4            matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
            global a;
            global img_final;
            [filename, pathname] = uigetfile('*.*', 'Pick an image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            img_final=a;
            imshow(a,'Parent',app.UIAxes);
            imshow(img_final,'Parent',app.UIAxes2);
        end

        % Button pushed function: RemoveBackgroundButton
        function RemoveBackgroundButtonPushed(app, event)
            global a;
            global option;
            global img_final
            img = a;
            imshow(img);
            img_final = img;
            switch option
                case 0
                    default;
                case 1 
                    r = drawrectangle;
                case 2 
                    r = drawcircle;
                case 3 
                    r = drawfreehand;
            end
            mask = createMask(r);
            if size(img,3) == 1
            img_final(~mask)=0;
            else
            % Break down and mask the planes:
            r = img(:,:,1);
            g = img(:,:,2);
            b = img(:,:,3);
            r(~mask) = 0;
            g(~mask) = 0;
            b(~mask) = 0;
            % Reconstruct the RGB image:
            img_final = cat(3,r,g,b);
            end
            imshow(img_final,'Parent',app.UIAxes2);    
        end

        % Button pushed function: BlurBackgroundButton
        function BlurBackgroundButtonPushed(app, event)
            global a
            global option
            global img_final
            img=a;
            img_object = img;
            img_background = img;
            imshow(img);
            switch option
                case 0
                    default;
                case 1 
                    r = drawrectangle;
                case 2 
                    r = drawcircle;
                case 3 
                    r = drawfreehand;
            end
            mask = createMask(r);
            if size(img,3) == 1
            img_object(~mask)=0;
            img_background(mask)=0;
            else
            r1 = img_object(:,:,1);
            g1 = img_object(:,:,2);
            b1 = img_object(:,:,3);
            r1(~mask) = 0;
            g1(~mask) = 0;
            b1(~mask) = 0;
            % Reconstruct the RGB image:
            img_object = cat(3,r1,g1,b1);
            r2 = img_background(:,:,1);
            g2 = img_background(:,:,2);
            b2 = img_background(:,:,3);
            r2(mask)=0;
            g2(mask)=0;
            b2(mask)=0;
            img_background = cat(3,r2,g2,b2);
            end
            B = imgaussfilt(img_background,5);
            img_final = img_object+B;
            imshow(img_final,'Parent',app.UIAxes2)
        end

        % Value changed function: ToolsDropDown
        function ToolsDropDownValueChanged(app, event)
            global option
            value = app.ToolsDropDown.Value;
            switch value
                case '-None-'
                    option = 0;
                case 'Rectangular'
                    option = 1;
                case 'Circle'
                    option = 2;
                case 'Free Hand'
                    option = 3;
            end
            
        end

        % Button pushed function: ExportImageButton
        function ExportImageButtonPushed(app, event)
            global img_final;
            filter = {'*.jpg';'*.png'};
            [filename, pathname] = uiputfile(filter);
            newfilename = fullfile(pathname, filename);
            imwrite(img_final,newfilename)
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            global img_final;
            global img_adjustment;
            value = app.BrightnessSlider.Value;
            %brightness adjustment by intensity sacling
            scale = value;
            img_adjustment = scale.*img_final;
            imshow(img_adjustment,'Parent',app.UIAxes2)
        end

        % Value changed function: RChannelSlider
        function RChannelSliderValueChanged(app, event)
            global img_final;
            global img_adjustment;
            value = app.RChannelSlider.Value;
            img_adjustment = img_final;
            %brightness adjustment by intensity sacling
            gamma = value;
            r = img_adjustment(:,:,1);
            g = img_adjustment(:,:,2);
            b = img_adjustment(:,:,3);
            r = r.*gamma;  
            img_adjustment = cat(3,r,g,b);
            imshow(img_adjustment,'Parent',app.UIAxes2)
        end

        % Value changed function: GChannelSlider
        function GChannelSliderValueChanged(app, event)
            global img_final;
            global img_adjustment;
            value = app.GChannelSlider.Value;
            img_adjustment = img_final;
            %brightness adjustment by intensity sacling
            gamma = value;
            r = img_adjustment(:,:,1);
            g = img_adjustment(:,:,2);
            b = img_adjustment(:,:,3);
            g = g.*gamma;  
            img_adjustment = cat(3,r,g,b);
            imshow(img_adjustment,'Parent',app.UIAxes2)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global img_adjustment;
            global img_final;
            img_final= img_adjustment;
            app.BrightnessSlider.Value = 1;
            imshow(img_final,'Parent',app.UIAxes2)
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            global img_adjustment;
            global img_final;
            img_final= img_adjustment;
            app.RChannelSlider.Value = 1;
            imshow(img_final,'Parent',app.UIAxes2)
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            global img_adjustment;
            global img_final;
            img_final= img_adjustment;
            app.GChannelSlider.Value = 1;
            imshow(img_final,'Parent',app.UIAxes2)
        end

        % Value changed function: BChannelSlider
        function BChannelSliderValueChanged(app, event)
            global img_final;
            global img_adjustment;
            value = app.BChannelSlider.Value;
            img_adjustment = img_final;
            %brightness adjustment by intensity sacling
            gamma = value;
            r = img_adjustment(:,:,1);
            g = img_adjustment(:,:,2);
            b = img_adjustment(:,:,3);
            b = b.*gamma;  
            img_adjustment = cat(3,r,g,b);
            imshow(img_adjustment,'Parent',app.UIAxes2)
        end

        % Button pushed function: SaveButton_4
        function SaveButton_4Pushed(app, event)
            global img_adjustment;
            global img_final;
            img_final= img_adjustment;
            app.BChannelSlider.Value = 1;
            imshow(img_final,'Parent',app.UIAxes2)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global img_final;
            global a;
            img_final = a;
            app.BrightnessSlider.Value = 1;
            app.RChannelSlider.Value = 1;
            app.GChannelSlider.Value = 1;
            app.BChannelSlider.Value = 1;
            imshow(img_final,'Parent',app.UIAxes2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Result')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ToolsDropDownLabel = uilabel(app.UIFigure);
            app.ToolsDropDownLabel.Text = 'Tools';
            app.ToolsDropDown = uidropdown(app.UIFigure);
            app.ToolsDropDown.Items = {'-None-', 'Rectangular', 'Circle', 'Free Hand'};
            app.ToolsDropDown.ValueChangedFcn = createCallbackFcn(app, @ToolsDropDownValueChanged, true);
            app.ToolsDropDown.Value = '-None-';
            app.ImportImageButton = uibutton(app.UIFigure, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Text = 'Import Image';
            app.BlurBackgroundButton = uibutton(app.UIFigure, 'push');
            app.BlurBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @BlurBackgroundButtonPushed, true);
            app.BlurBackgroundButton.Text = 'Blur Background';
            app.RemoveBackgroundButton = uibutton(app.UIFigure, 'push');
            app.RemoveBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBackgroundButtonPushed, true);
            app.RemoveBackgroundButton.Text = 'Remove Background';
            app.IMAGEPROCESSINGPROJECTNGUYENXUANHAOEEACIU19005Label = uilabel(app.UIFigure);
            app.IMAGEPROCESSINGPROJECTNGUYENXUANHAOEEACIU19005Label.Text = {'IMAGE PROCESSING PROJECT'; 'NGUYEN XUAN HAO - EEACIU19005'};
            app.ExportImageButton = uibutton(app.UIFigure, 'push');
            app.ExportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImageButtonPushed, true);
            app.ExportImageButton.Text = 'Export Image';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BrightnessTab = uitab(app.TabGroup);
            app.BrightnessTab.Title = 'Brightness';
            app.BrightnessSliderLabel = uilabel(app.BrightnessTab);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.BrightnessTab);
            app.BrightnessSlider.Limits = [1 2];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.Value = 1;
            app.SaveButton = uibutton(app.BrightnessTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ColorchannelsTab = uitab(app.TabGroup);
            app.ColorchannelsTab.Title = 'Color channels';
            app.RChannelSliderLabel = uilabel(app.ColorchannelsTab);
            app.RChannelSliderLabel.Text = 'R-Channel';
            app.RChannelSlider = uislider(app.ColorchannelsTab);
            app.RChannelSlider.Limits = [1 2];
            app.RChannelSlider.ValueChangedFcn = createCallbackFcn(app, @RChannelSliderValueChanged, true);
            app.RChannelSlider.Value = 1;
            app.GChannelSliderLabel = uilabel(app.ColorchannelsTab);
            app.GChannelSliderLabel.Text = 'G-Channel';
            app.GChannelSlider = uislider(app.ColorchannelsTab);
            app.GChannelSlider.Limits = [1 2];
            app.GChannelSlider.ValueChangedFcn = createCallbackFcn(app, @GChannelSliderValueChanged, true);
            app.GChannelSlider.Value = 1;
            app.BChannelSliderLabel = uilabel(app.ColorchannelsTab);
            app.BChannelSliderLabel.Text = 'B-Channel';
            app.BChannelSlider = uislider(app.ColorchannelsTab);
            app.BChannelSlider.Limits = [1 2];
            app.BChannelSlider.ValueChangedFcn = createCallbackFcn(app, @BChannelSliderValueChanged, true);
            app.BChannelSlider.Value = 1;
            app.SaveButton_2 = uibutton(app.ColorchannelsTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SaveButton_3 = uibutton(app.ColorchannelsTab, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Text = 'Save';
            app.SaveButton_4 = uibutton(app.ColorchannelsTab, 'push');
            app.SaveButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_4Pushed, true);
            app.SaveButton_4.Text = 'Save';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IPL_NguyenXuanHao_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end